classdef FlappyBirdus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResetButton          matlab.ui.control.Button
        ScoreEditField       matlab.ui.control.NumericEditField
        ScoreEditFieldLabel  matlab.ui.control.Label
        ExitButton           matlab.ui.control.Button
        StartButton          matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        birdPos = [3,7];
        birdSpeed = [3,0];
        gravity_acc = 9.81;
        time_step = 0.05;
        axlim = 0;
        counter = 1;
        gaemovr = 0;
        pillarInfo
        handle
        levelLength = 50;
        pipeWitdth = 1.5;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %make intial scatter
            app.handle = scatter(app.UIAxes, app.birdPos(1),app.birdPos(2),1000,'filled','MarkerFaceColor','y','MarkerEdgeColor','k','Marker','>');
            app.pillarInfo = zeros(app.levelLength,2);
            %make pillars
            for i = 1:app.levelLength
                bgr = 2;
                x = randi([bgr,round(10-bgr-app.pipeWitdth)]);
                if i>=3
                rectangle(app.UIAxes, 'Position',[i*6,0,2,x(1)],'FaceColor',[0.4 1 0.4],'EdgeColor','k');
                rectangle(app.UIAxes, 'Position',[i*6,x(1)+app.pipeWitdth,2,10-app.pipeWitdth-x(1)],'FaceColor',[0.4 1 0.4],'EdgeColor','k');
                app.pillarInfo(i,:) = [x(1),i*6]; %contains [minPillar1, xStartPos; ... ]
                elseif i==1
                rectangle(app.UIAxes, 'Position',[i*6,0,2,bgr],'FaceColor',[0.4 1 0.4],'EdgeColor','k');
                rectangle(app.UIAxes, 'Position',[i*6,bgr+app.pipeWitdth,2,10-app.pipeWitdth-bgr],'FaceColor',[0.4 1 0.4],'EdgeColor','k');
                app.pillarInfo(i,:) = [bgr,i*6]; %contains [minPillar1, xStartPos; ... ]       
                else
                rectangle(app.UIAxes, 'Position',[i*6,0,2,round(10-bgr-app.pipeWitdth)],'FaceColor',[0.4 1 0.4],'EdgeColor','k');
                rectangle(app.UIAxes, 'Position',[i*6,round(10-bgr-app.pipeWitdth)+app.pipeWitdth,2,10-round(10-bgr-app.pipeWitdth)-app.pipeWitdth],'FaceColor',[0.4 1 0.4],'EdgeColor','k');
                app.pillarInfo(i,:) = [round(10-bgr-app.pipeWitdth),i*6]; %contains [minPillar1, xStartPos; ... ]       
                end
            end

            app.UIAxes.Color = [0.4 0.4 1];
            app.axlim =  app.axlim + 3*app.time_step;
            app.UIAxes.XLim = [ app.axlim  app.axlim+10];
            app.UIAxes.YLim = [0 10];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            while app.birdPos(2) > 0 && app.gaemovr==0
                %check colisilon
                if app.birdPos(1)>app.pillarInfo(app.counter,2)+app.pipeWitdth
                    app.ScoreEditField.Value = app.counter;
                    app.counter = app.counter + 1;
                elseif app.birdPos(1)>=app.pillarInfo(app.counter,2)-.15
                    if app.birdPos(2)<=app.pillarInfo(app.counter,1)+.15 || app.birdPos(2)>=app.pillarInfo(app.counter,1)+app.pipeWitdth-0.15
                        app.gaemovr = 1;
                    end
                end

                %plot bird
                app.handle.XData = app.birdPos(1);
                app.handle.YData = app.birdPos(2);

                app.UIAxes.Color = [0.4 0.4 1];
                app.axlim =  app.axlim + app.birdSpeed(1)*app.time_step;
                app.UIAxes.XLim = [app.axlim  app.axlim+10];
                app.UIAxes.YLim = [0 10];
                pause(1/5)

                %apply constant horizontal speed
                app.birdPos(1) = app.birdPos(1) + app.birdSpeed(1)*app.time_step;

                %let gravity act
                app.birdSpeed(2) = app.birdSpeed(2) - app.gravity_acc*app.time_step;
                app.birdPos(2) = app.birdPos(2) + app.birdSpeed(2)*app.time_step;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
             app.delete % Shut down the entire app
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            %key = event.Key;
            app.birdSpeed(2) = 4;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.birdPos = [3,7];
            app.birdSpeed = [2,0];
            app.gaemovr = 0;
            app.counter = 1;
            app.axlim = 0;
            app.ScoreEditField.Value = 0;
            startupFcn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = {'Reset'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlappyBirdus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end